classdef AudioManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Title                       matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        MeasurementpositionsTab     matlab.ui.container.Tab
        Plot1                       matlab.ui.control.UIAxes
        Plot2                       matlab.ui.control.UIAxes
        Plot3                       matlab.ui.control.UIAxes
        Plot4                       matlab.ui.control.UIAxes
        Play1                       matlab.ui.control.Button
        DropDown_1                  matlab.ui.control.DropDown
        DropDown_2                  matlab.ui.control.DropDown
        DropDown_3                  matlab.ui.control.DropDown
        DropDown_4                  matlab.ui.control.DropDown
        Stop1                       matlab.ui.control.Button
        Play2                       matlab.ui.control.Button
        Stop2                       matlab.ui.control.Button
        Play3                       matlab.ui.control.Button
        Stop3                       matlab.ui.control.Button
        Play4                       matlab.ui.control.Button
        Stop4                       matlab.ui.control.Button
        StereoselectionTab          matlab.ui.container.Tab
        DropDown_Left               matlab.ui.control.DropDown
        DropDown_Right              matlab.ui.control.DropDown
        LeftLabel                   matlab.ui.control.Label
        RightLabel                  matlab.ui.control.Label
        PlotLeft                    matlab.ui.control.UIAxes
        PlayStereo                  matlab.ui.control.Button
        StopStereo                  matlab.ui.control.Button
        PlotRight                   matlab.ui.control.UIAxes
        LeftLabel_2                 matlab.ui.control.Label
        RightLabel_2                matlab.ui.control.Label
        SetStereo                   matlab.ui.control.Button
        StereoselectedListBoxLabel  matlab.ui.control.Label
        StereoselectedListBox       matlab.ui.control.ListBox
        SelectStereo                matlab.ui.control.Button
        PlayList                    matlab.ui.control.Button
        StopList                    matlab.ui.control.Button
        ClearList                   matlab.ui.control.Button
        OpenfileButton              matlab.ui.control.Button
    end


    properties (Access = private)
        StructCreated = false % Description
        j = 0;
        pList;
        stopAudioList = false
    end


    methods (Access = private)

        % Button pushed function: OpenfileButton
        function OpenfileButtonPushed(app, event)
            clear Fs time SignalName Names y yMax p
            
            [file path] = uigetfile('*.mat');
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            if file == 0
                return
            end
            
            %file = open(file);
            
            file = importdata(fullfile(path, file));
                        
            file_cell = struct2cell(file);
            data = file_cell{1};
            
            RunName = cell2mat(data.function_record.TL_export_properties_annotation.run_name(1,1))';
            app.Title.Text = ['Project: ',cell2mat(data.function_record.TL_export_properties_annotation.project_name(1,1))'...
                ,' -- Section: ',cell2mat(data.function_record.TL_export_properties_annotation.section_name(1,1))'...
                ,' -- Run: ', RunName];
            
             
            Fs = 1/data.x_values.increment;
            time = 0:1/Fs:(data.x_values.number_of_values-1)/Fs;
            SignalName = data.function_record.name;
            Names = string(zeros(1,length(SignalName)));
            
            y = zeros(length(data.y_values.values),length(SignalName));
            
            for i = 1:length(SignalName)
                Names(:,i) = string((SignalName{1,i}'));
                y(:,i) = data.y_values.values(:,i);
            end
            
            yMax = max([max(y) abs(min(y))]);
            
            for i = 1:length(SignalName)
                p(i) = audioplayer(y(:,i)/yMax,Fs);
                setappdata(app.DropDown_Left,'yLeft',y(:,i)/yMax);
                setappdata(app.DropDown_Right,'yRight',y(:,i)/yMax);
            end            
            
            % Plots iniciales
            
                    plot(app.Plot1,time, y(:,1)), title(app.Plot1,Names(1));
                    xlabel(app.Plot1,'time (s)');
                    ylabel(app.Plot1,'Amplitude (RMS)');
                    ylim(app.Plot1,[-yMax,yMax]);
                    xlim(app.Plot1,[0 max(time)]);
                    
                    plot(app.Plot2,time, y(:,1)), title(app.Plot2,Names(1));
                    xlabel(app.Plot2,'time (s)');
                    ylabel(app.Plot2,'Amplitude (RMS)');
                    ylim(app.Plot2,[-yMax,yMax]);
                    xlim(app.Plot2,[0 max(time)]);
                    
                    plot(app.Plot3,time, y(:,1)), title(app.Plot3,Names(1));
                    xlabel(app.Plot3,'time (s)');
                    ylabel(app.Plot3,'Amplitude (RMS)');
                    ylim(app.Plot3,[-yMax,yMax]);
                    xlim(app.Plot3,[0 max(time)]);
                    
                    plot(app.Plot4,time, y(:,1)), title(app.Plot4,Names(1));
                    xlabel(app.Plot4,'time (s)');
                    ylabel(app.Plot4,'Amplitude (RMS)');
                    ylim(app.Plot4,[-yMax,yMax]);
                    xlim(app.Plot4,[0 max(time)]);
                    
                    plot(app.PlotLeft,time, y(:,1)), title(app.PlotLeft,Names(1));
                    xlabel(app.PlotLeft,'time (s)');
                    ylabel(app.PlotLeft,'Amplitude (RMS)');
                    ylim(app.PlotLeft,[-yMax,yMax]);
                    xlim(app.PlotLeft,[0 max(time)]);
                    
                    plot(app.PlotRight,time, y(:,1)), title(app.PlotRight,Names(1));
                    xlabel(app.PlotRight,'time (s)');
                    ylabel(app.PlotRight,'Amplitude (RMS)');
                    ylim(app.PlotRight,[-yMax,yMax]);
                    xlim(app.PlotRight,[0 max(time)]);
            
            % Set datos
            
            app.DropDown_1.Items = SignalName;
            app.DropDown_2.Items = SignalName;
            app.DropDown_3.Items = SignalName;
            app.DropDown_4.Items = SignalName;
            app.DropDown_Left.Items = SignalName;
            app.DropDown_Right.Items = SignalName;
            
            setappdata(app.OpenfileButton, 'RunName', RunName);
            setappdata(app.OpenfileButton,'Fs',Fs);
            setappdata(app.OpenfileButton,'time',time);
            setappdata(app.OpenfileButton,'SignalName',SignalName);
            setappdata(app.OpenfileButton,'Names',Names);
            setappdata(app.OpenfileButton,'y',y);
            setappdata(app.OpenfileButton,'yMax',yMax);
            setappdata(app.OpenfileButton, 'p', p);
        end

        % Value changed function: DropDown_1
        function DropDown_1ValueChanged(app, event)
            time = getappdata(app.OpenfileButton,'time');
            y = getappdata(app.OpenfileButton,'y');
            yMax = getappdata(app.OpenfileButton,'yMax');
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            
            value = app.DropDown_1.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    plot(app.Plot1,time, y(:,i)), title(app.Plot1,Names(i));
                    xlabel(app.Plot1,'time (s)');
                    ylabel(app.Plot1,'Amplitude (RMS)');
                    ylim(app.Plot1,[-yMax,yMax]);
                    xlim(app.Plot1,[0 max(time)]);
                end
            end            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            time = getappdata(app.OpenfileButton,'time');
            y = getappdata(app.OpenfileButton,'y');
            yMax = getappdata(app.OpenfileButton,'yMax');
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            
            value = app.DropDown_2.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    plot(app.Plot2,time, y(:,i)), title(app.Plot2,Names(i));
                    xlabel(app.Plot2,'time (s)');
                    ylabel(app.Plot2,'Amplitude (RMS)');
                    ylim(app.Plot2,[-yMax,yMax]);
                    xlim(app.Plot2,[0 max(time)]);
                end
            end    
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            time = getappdata(app.OpenfileButton,'time');
            y = getappdata(app.OpenfileButton,'y');
            yMax = getappdata(app.OpenfileButton,'yMax');
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            
            value = app.DropDown_3.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    plot(app.Plot3,time, y(:,i)), title(app.Plot3,Names(i));
                    xlabel(app.Plot3,'time (s)');
                    ylabel(app.Plot3,'Amplitude (RMS)');
                    ylim(app.Plot3,[-yMax,yMax]);
                    xlim(app.Plot3,[0 max(time)]);
                end
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            time = getappdata(app.OpenfileButton,'time');
            y = getappdata(app.OpenfileButton,'y');
            yMax = getappdata(app.OpenfileButton,'yMax');
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            
            value = app.DropDown_4.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    plot(app.Plot4,time, y(:,i)), title(app.Plot4,Names(i));
                    xlabel(app.Plot4,'time (s)');
                    ylabel(app.Plot4,'Amplitude (RMS)');
                    ylim(app.Plot4,[-yMax,yMax]);
                    xlim(app.Plot4,[0 max(time)]);
                end
            end
        end

        % Button pushed function: Play1
        function Play1_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_1.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    play(p(i))
                end
            end
        end

        % Button pushed function: Play2
        function Play2_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_2.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    play(p(i))
                end
            end
        end

        % Button pushed function: Play3
        function Play3_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_3.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    play(p(i))
                end
            end
        end

        % Button pushed function: Play4
        function Play4_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_4.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    play(p(i))
                end
            end
        end

        % Button pushed function: Stop1
        function Stop1_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_1.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    stop(p(i))
                end
            end
        end

        % Button pushed function: Stop2
        function Stop2_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_2.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    stop(p(i))
                end
            end
        end

        % Button pushed function: Stop3
        function Stop3_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_3.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    stop(p(i))
                end
            end
        end

        % Button pushed function: Stop4
        function Stop4_ButtonPushed(app, event)
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            p = getappdata(app.OpenfileButton, 'p');
            
            value = app.DropDown_4.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    stop(p(i))
                end
            end
        end

        % Value changed function: DropDown_Left
        function DropDown_LeftValueChanged(app, event)
            time = getappdata(app.OpenfileButton,'time');
            y = getappdata(app.OpenfileButton,'y');
            yMax = getappdata(app.OpenfileButton,'yMax');
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            
            value = app.DropDown_Left.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    plot(app.PlotLeft,time, y(:,i)), title(app.PlotLeft,Names(i));
                    xlabel(app.PlotLeft,'time (s)');
                    ylabel(app.PlotLeft,'Amplitude (RMS)');
                    ylim(app.PlotLeft,[-yMax,yMax]);
                    xlim(app.PlotLeft,[0 max(time)]);
                    yLeft = y(:,i)/yMax;
                end
            end
            
            setappdata(app.DropDown_Left,'yLeft',yLeft);
            
        end

        % Value changed function: DropDown_Right
        function DropDown_RightValueChanged(app, event)
            time = getappdata(app.OpenfileButton,'time');
            y = getappdata(app.OpenfileButton,'y');
            yMax = getappdata(app.OpenfileButton,'yMax');
            Names = getappdata(app.OpenfileButton, 'Names');
            SignalName = getappdata(app.OpenfileButton, 'SignalName');
            
            value = app.DropDown_Right.Value';
            
            for i = 1:length(SignalName)
                if strcmp(value,Names(i)) == 1
                    plot(app.PlotRight,time, y(:,i)), title(app.PlotRight,Names(i));
                    xlabel(app.PlotRight,'time (s)');
                    ylabel(app.PlotRight,'Amplitude (RMS)');
                    ylim(app.PlotRight,[-yMax,yMax]);
                    xlim(app.PlotRight,[0 max(time)]);
                    yRight = y(:,i)/yMax;
                end
            end
            
            setappdata(app.DropDown_Right,'yRight',yRight);
            
        end

        % Button pushed function: PlayStereo
        function PlayStereoButtonPushed(app, event)
            pStereo = getappdata(app.SetStereo, 'pStereo');
            play(pStereo);            
        end

        % Button pushed function: StopStereo
        function StopStereoButtonPushed(app, event)
            pStereo = getappdata(app.SetStereo, 'pStereo');
            stop(pStereo);
        end

        % Button pushed function: SetStereo
        function SetStereo_ButtonPushed(app, event)
            yLeft = getappdata(app.DropDown_Left, 'yLeft');
            yRight = getappdata(app.DropDown_Right, 'yRight');
            Fs = getappdata(app.OpenfileButton, 'Fs');
            yStereoComparison = getappdata(app.SetStereo,'yStereoComparison');
            
            yStereo = [yLeft yRight];
            
            pStereo = audioplayer(yStereo, Fs);
            
            %if app.StereoSelected == 'false'
            %    yStereoComparisonInit = [];    
            %end            
            
            yStereoComparisonInit = [yStereoComparison];
            
            setappdata(app.SetStereo,'pStereo',pStereo);
            setappdata(app.SetStereo,'yStereo',yStereo);
            
            app.PlayStereo.Enable = 'On';
            app.StopStereo.Enable = 'On';
        end

        % Button pushed function: SelectStereo
        function SelectStereoButtonPushed(app, event)
            RunName = getappdata(app.OpenfileButton, 'RunName');
            pStereo = getappdata(app.SetStereo,'pStereo');
            
            if app.StructCreated == false
                app.pList.pStereo1 = pStereo;
                app.StructCreated = true;
                app.j = 1;
            else
                app.j=app.j+1;
                eval(['app.pList.pStereo' num2str(app.j) '= pStereo']);    
            end
            
            %setappdata(app.SelectStereo,'pList',pList);
            app.StereoselectedListBox.Items = [app.StereoselectedListBox.Items, [app.j RunName]];            
        end

        % Button pushed function: PlayList
        function PlayListButtonPushed(app, event)
            %pList = getappdata(app.SelectStereo,'pList');
            app.stopAudioList = false
            if length(app.pList) >= 1
                for i = 1:numel(fieldnames(app.pList))
                    set(app.StereoselectedListBox, 'Value', char(app.StereoselectedListBox.Items(i)))
                    playblocking(eval(['app.pList.pStereo' num2str(i)]));
                    pause(1.5);
                    if app.stopAudioList == true
                        return
                    end
                end                
            end
        end

        % Button pushed function: StopList
        function StopListButtonPushed(app, event)
            if length(app.pList) >= 1
                for i = 1:numel(fieldnames(app.pList))
                    stop(eval(['app.pList.pStereo' num2str(i)]));
                end
                app.stopAudioList = true;
            end
        end

        % Button pushed function: ClearList
        function ClearListButtonPushed(app, event)
            if length(app.pList) >= 1
                app.pList = {};
                app.StereoselectedListBox.Items = {};
                app.j = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeasurementpositionsTab = uitab(app.TabGroup);
            app.MeasurementpositionsTab.Title = 'Measurement positions';
            app.MeasurementpositionsTab.Units = 'pixels';
            app.Plot1 = uiaxes(app.MeasurementpositionsTab);
            title(app.Plot1, 'Title');
            xlabel(app.Plot1, 'X');
            ylabel(app.Plot1, 'Y');
            app.Plot2 = uiaxes(app.MeasurementpositionsTab);
            title(app.Plot2, 'Title');
            xlabel(app.Plot2, 'X');
            ylabel(app.Plot2, 'Y');
            app.Plot3 = uiaxes(app.MeasurementpositionsTab);
            title(app.Plot3, 'Title');
            xlabel(app.Plot3, 'X');
            ylabel(app.Plot3, 'Y');
            app.Plot4 = uiaxes(app.MeasurementpositionsTab);
            title(app.Plot4, 'Title');
            xlabel(app.Plot4, 'X');
            ylabel(app.Plot4, 'Y');
            app.Play1 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Play1.ButtonPushedFcn = createCallbackFcn(app, @Play1_ButtonPushed, true);
            app.Play1.Text = 'Play';
            app.DropDown_1 = uidropdown(app.MeasurementpositionsTab);
            app.DropDown_1.Items = {};
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_1.Value = {};
            app.DropDown_2 = uidropdown(app.MeasurementpositionsTab);
            app.DropDown_2.Items = {};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = {};
            app.DropDown_3 = uidropdown(app.MeasurementpositionsTab);
            app.DropDown_3.Items = {};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = {};
            app.DropDown_4 = uidropdown(app.MeasurementpositionsTab);
            app.DropDown_4.Items = {};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = {};
            app.Stop1 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Stop1.ButtonPushedFcn = createCallbackFcn(app, @Stop1_ButtonPushed, true);
            app.Stop1.Text = 'Stop';
            app.Play2 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Play2.ButtonPushedFcn = createCallbackFcn(app, @Play2_ButtonPushed, true);
            app.Play2.Text = 'Play';
            app.Stop2 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Stop2.ButtonPushedFcn = createCallbackFcn(app, @Stop2_ButtonPushed, true);
            app.Stop2.Text = 'Stop';
            app.Play3 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Play3.ButtonPushedFcn = createCallbackFcn(app, @Play3_ButtonPushed, true);
            app.Play3.Text = 'Play';
            app.Stop3 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Stop3.ButtonPushedFcn = createCallbackFcn(app, @Stop3_ButtonPushed, true);
            app.Stop3.Text = 'Stop';
            app.Play4 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Play4.ButtonPushedFcn = createCallbackFcn(app, @Play4_ButtonPushed, true);
            app.Play4.Text = 'Play';
            app.Stop4 = uibutton(app.MeasurementpositionsTab, 'push');
            app.Stop4.ButtonPushedFcn = createCallbackFcn(app, @Stop4_ButtonPushed, true);
            app.Stop4.Text = 'Stop';
            app.StereoselectionTab = uitab(app.TabGroup);
            app.StereoselectionTab.Title = 'Stereo selection';
            app.StereoselectionTab.Units = 'pixels';
            app.DropDown_Left = uidropdown(app.StereoselectionTab);
            app.DropDown_Left.Items = {};
            app.DropDown_Left.ValueChangedFcn = createCallbackFcn(app, @DropDown_LeftValueChanged, true);
            app.DropDown_Left.Value = {};
            app.DropDown_Right = uidropdown(app.StereoselectionTab);
            app.DropDown_Right.Items = {};
            app.DropDown_Right.ValueChangedFcn = createCallbackFcn(app, @DropDown_RightValueChanged, true);
            app.DropDown_Right.Value = {};
            app.LeftLabel = uilabel(app.StereoselectionTab);
            app.LeftLabel.Text = 'Left';
            app.RightLabel = uilabel(app.StereoselectionTab);
            app.RightLabel.Text = 'Right';
            app.PlotLeft = uiaxes(app.StereoselectionTab);
            title(app.PlotLeft, 'Title');
            xlabel(app.PlotLeft, 'X');
            ylabel(app.PlotLeft, 'Y');
            app.PlayStereo = uibutton(app.StereoselectionTab, 'push');
            app.PlayStereo.ButtonPushedFcn = createCallbackFcn(app, @PlayStereoButtonPushed, true);
            app.PlayStereo.Enable = 'off';
            app.PlayStereo.Text = 'Play';
            app.StopStereo = uibutton(app.StereoselectionTab, 'push');
            app.StopStereo.ButtonPushedFcn = createCallbackFcn(app, @StopStereoButtonPushed, true);
            app.StopStereo.Enable = 'off';
            app.StopStereo.Text = 'Stop';
            app.PlotRight = uiaxes(app.StereoselectionTab);
            title(app.PlotRight, 'Title');
            xlabel(app.PlotRight, 'X');
            ylabel(app.PlotRight, 'Y');
            app.LeftLabel_2 = uilabel(app.StereoselectionTab);
            app.LeftLabel_2.Text = 'Left';
            app.RightLabel_2 = uilabel(app.StereoselectionTab);
            app.RightLabel_2.Text = 'Right';
            app.SetStereo = uibutton(app.StereoselectionTab, 'push');
            app.SetStereo.ButtonPushedFcn = createCallbackFcn(app, @SetStereo_ButtonPushed, true);
            app.SetStereo.Text = 'Set';
            app.StereoselectedListBoxLabel = uilabel(app.StereoselectionTab);
            app.StereoselectedListBoxLabel.Text = 'Stereo selected';
            app.StereoselectedListBox = uilistbox(app.StereoselectionTab);
            app.StereoselectedListBox.Items = {};
            app.StereoselectedListBox.Multiselect = 'on';
            app.StereoselectedListBox.Value = {};
            app.SelectStereo = uibutton(app.StereoselectionTab, 'push');
            app.SelectStereo.ButtonPushedFcn = createCallbackFcn(app, @SelectStereoButtonPushed, true);
            app.SelectStereo.Text = 'Select';
            app.PlayList = uibutton(app.StereoselectionTab, 'push');
            app.PlayList.ButtonPushedFcn = createCallbackFcn(app, @PlayListButtonPushed, true);
            app.PlayList.Text = 'Play';
            app.StopList = uibutton(app.StereoselectionTab, 'push');
            app.StopList.ButtonPushedFcn = createCallbackFcn(app, @StopListButtonPushed, true);
            app.StopList.Text = 'Stop';
            app.ClearList = uibutton(app.StereoselectionTab, 'push');
            app.ClearList.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearList.Text = 'Clear';
            app.OpenfileButton = uibutton(app.UIFigure, 'push');
            app.OpenfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfileButtonPushed, true);
            app.OpenfileButton.Text = 'Open file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudioManager()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end